classdef Curve_Fitter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        TextArea              matlab.ui.control.TextArea
        YValuesDropDown       matlab.ui.control.DropDown
        YValuesDropDownLabel  matlab.ui.control.Label
        UITable3              matlab.ui.control.Table
        UITable2              matlab.ui.control.Table
        UITable               matlab.ui.control.Table
        ModelDropDown         matlab.ui.control.DropDown
        ModelDropDownLabel    matlab.ui.control.Label
        XValuesDropDown       matlab.ui.control.DropDown
        XValuesDropDownLabel  matlab.ui.control.Label
        PlotButton            matlab.ui.control.StateButton
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        menu_timer % Description
        xVal % Description
        yVal
    end
    
    methods (Access = private)

        function plot_model(app, ~, ~)
            x = app.xVal;
            y = app.yVal;
            a = 0;
            b = 0;
            A = 0;
            B = 0;
            if isempty(x) | isempty(y)
                f = errordlg('Values cannot be empty', 'VALUE ERROR');
                return
            end

            try 
                 C = [app.xVal; app.yVal];
            catch ME
                f = errordlg('Matrix size mismatch','MATRIX ERROR');
                return
            end

            switch app.ModelDropDown.Value
                case '1st Degree Polynomial'
                    X = x;
                    Y = y;
                    [A,B] = calc_coeff(app, X,Y);
                    a=A;
                    b=B;                    
                    f = a +b*x;
                case '2nd Degree Polynomial'
                    plot_2nd_deg(app);
                    return
                case 'Exponential'
                    X = x;
                    Y= log(y);
                    [A,B] = calc_coeff(app, X,Y);
                    a = exp(A);
                    b=B;                  
                    f= a*exp(b*x);

                case 'Power'
                    X = log10(x);
                    Y = log10(y);
                    [A,B] = calc_coeff(app, X,Y);
                    a = 10^A;
                    b = 10^B;
                    f = a*(x.^b);
                    
                case 'Growth Rate'
                    X=1./x;
                    Y=1./y;
                    [A,B] = calc_coeff(app, X,Y);
                    a = 1/A;
                    b = a*B;
                    f = a*x./(x+b);

            end
            n = length(x);
            st = sum((Y-mean(Y)).^2);
            sr = sum((Y-A-B*X).^2);
            sy= sqrt(st/(n-1));
            syx=sqrt(sr/(n-2));
            r2 =(st-sr)/st;
            plot(app.UIAxes,x,y,'*',x,f)
            app.UITable.Data = {st sr};
            app.UITable2.Data = {sy syx};
            app.UITable3.Data = {sqrt(r2) r2};
            update_text(app, a, b);
            %TODO
        end

        function [a0, a1] = calc_coeff(app, x, y)
            n=length(x);
            coef = [n sum(x);sum(x) sum(x.^2)];
            free=[sum(y);sum(x.*y)];
            v = (inv(coef)*free);
            a0 = v(1);
            a1 = v(2);
            
        end
        function update_text(app, a, b)
            str=sprintf('%s\n',...
                        'Coeffecients: ', ...
                        sprintf("a =  %d", a),...
                        sprintf("b =  %d", b));

            app.TextArea.Value = str;

        end
        function plot_2nd_deg(app)
            x = app.xVal;
            y = app.yVal;
            n=length(x);
            coef = [n sum(x) sum(x.^2) ;sum(x) sum(x.^2) sum(x.^3);sum(x.^2) sum(x.^3) sum(x.^4)];
            free=[sum(y);sum(x.*y);sum(x.^2.*y)];
            V= inv(coef)*free;
            a=V(1);
            b=V(2);
            c= V(3);
            f = a +b*x +c*x.^2;
            plot(app.UIAxes,x,y,'*',x,f);
            st = sum((y-mean(y)).^2);
            sr = sum((y-a-b*x-c*x.^2).^2);
            sy= sqrt(st/(n-1));
            syx=sqrt(sr/(n-3));
            r2 =(st-sr)/st;
            app.UITable.Data = {st sr};
            app.UITable2.Data = {sy syx};
            app.UITable3.Data = {sqrt(r2) r2};

            str=sprintf('%s\n',...
                        'Coeffecients: ', ...
                        sprintf("a =  %d", a),...
                        sprintf("b =  %d", b),...
                        sprintf("c =  %d", c));

            app.TextArea.Value = str;

        end
        
        function update_menu(app, ~, ~)
            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            cell_array{1} = '';
            for i=2:length(vars)+1
                cell_array{i} = vars(i-1).name;
            end
            app.XValuesDropDown.Items = cell_array;
            app.YValuesDropDown.Items = cell_array;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Curve Fitter';
            app.menu_timer = timer('ExecutionMode', 'fixedRate', ...
                'Period', 1, ...
                'TimerFcn', @app.update_menu);
            start(app.menu_timer);
            app.update_menu;
            valueX = app.XValuesDropDown.Value;
            valueY = app.YValuesDropDown.Value;
            if isempty(valueX) == 0  | isempty(valueY) == 0
                dataX = evalin('base', valueX);
                dataY = evalin('base', valueY);
                app.xVal = dataX;
                app.yVal = dataY;
            end
            app.TextArea.Value = ''; 


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.menu_timer);
            delete(app.menu_timer);
            delete(app)
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)

        end

        % Value changed function: XValuesDropDown
        function XValuesDropDownValueChanged(app, event)
            value = app.XValuesDropDown.Value;
            if isempty(value)
                app.xVal = [];
                return
            end
            data = evalin('base', value);
            app.xVal = data;
            
        end

        % Value changed function: PlotButton
        function PlotButtonValueChanged(app, event)
            plot_model(app)
        end

        % Value changed function: YValuesDropDown
        function YValuesDropDownValueChanged2(app, event)
            value = app.YValuesDropDown.Value;
            if isempty(value)
                app.yVal = [];
                return
            end
            data = evalin('base', value);
            app.yVal = data;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Model')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlotButton = uibutton(app.GridLayout, 'state');
            app.PlotButton.ValueChangedFcn = createCallbackFcn(app, @PlotButtonValueChanged, true);
            app.PlotButton.Text = 'Plot';
            app.XValuesDropDownLabel = uilabel(app.GridLayout);
            app.XValuesDropDownLabel.Text = 'X- Values';
            app.XValuesDropDown = uidropdown(app.GridLayout);
            app.XValuesDropDown.ValueChangedFcn = createCallbackFcn(app, @XValuesDropDownValueChanged, true);
            app.ModelDropDownLabel = uilabel(app.GridLayout);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.GridLayout);
            app.ModelDropDown.Items = {'1st Degree Polynomial', '2nd Degree Polynomial', 'Exponential', 'Power', 'Growth Rate'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = '1st Degree Polynomial';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'St'; 'Sr'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.GridLayout);
            app.UITable2.ColumnName = {'Sy'; 'Sy/x'};
            app.UITable2.RowName = {};
            app.UITable3 = uitable(app.GridLayout);
            app.UITable3.ColumnName = {'R-Squared'; 'R'};
            app.UITable3.RowName = {};
            app.YValuesDropDownLabel = uilabel(app.GridLayout);
            app.YValuesDropDownLabel.Text = 'Y- Values';
            app.YValuesDropDown = uidropdown(app.GridLayout);
            app.YValuesDropDown.ValueChangedFcn = createCallbackFcn(app, @YValuesDropDownValueChanged2, true);
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Editable = 'off';
            app.TextArea.Tooltip = {''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Curve_Fitter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end